classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField           matlab.ui.control.EditField
        DataEditField       matlab.ui.control.EditField
        DataEditFieldLabel  matlab.ui.control.Label
        DecryptVideoButton  matlab.ui.control.Button
        EncryptVideoButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        oldpath = addpath('D:\project\Main Project\Matlab-Project\') % to add path
    end
    
    methods (Access = private)
        
        function results = processVideo(app,event);
            global a;
            app.EditField.Value='Choose video';
            %Step for choosing the video
            [filename, pathname] = uigetfile('D:\project\Main Project\Matlab-Project/*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            %Changing the current folder
            cd (pathname)
            cd ..
            a=VideoReader(filename);
            
            vid = read(a);
             % read the total number of frames
            frames = a.NumFrames;
            % file format of the frames to be saved in
            ST ='.jpg';
            % reading and writing the frames 
            app.EditField.Value='Converting to frames';
            for x = 1 : frames
              
                % converting integer to string
                Sx = num2str(x);
                % concatenating 2 strings
                Strc = strcat(Sx, ST);
                Vid = vid(:, :, :, x);
                cd frames\original
                app.EditField.Value=num2str((x/frames)*100);
                % exporting the frames
                pause(0.000001);
                imwrite(Vid, Strc);
                cd ..\..
            end
            %Selecting target video
            %Selecting total number of target videos
            app.EditField.Value='Selecting target video';
            targetVideoFolder='D:\project\Main Project\Matlab-Project\video\targetVideo\';
            targetVideoPath=dir([targetVideoFolder '/*.mp4']);
            numberOfTargetVideos=size(targetVideoPath,1);
            %Choosing best target video
            targetVideoChosen=VideoReader('D:\project\Main Project\Matlab-Project\video\targetVideo\1.mp4');
            for x=1 : numberOfTargetVideos
                cd video\targetVideo
                Sx = num2str(x);
                filenameOfTargetVideo=strcat(Sx,'.mp4');
                tempVideo=VideoReader(filenameOfTargetVideo);
                tempVideoFrameNum=tempVideo.NumFrames;
                %Comparing for best targetVideo
                if (tempVideoFrameNum>=frames) && (tempVideoFrameNum<targetVideoChosen.NumFrames)
                    targetVideoChosen=tempVideo;
                end    
                cd ..\..    
            end    
            %Converting the target Video to frames
            app.EditField.Value="Converting target video to frames";
            vid1=read(targetVideoChosen);
            noOfTargetVideoFrames=targetVideoChosen.NumFrames;
            for x = 1 : noOfTargetVideoFrames
                % converting integer to string
                Sx = num2str(x);
                % concatenating 2 strings
                Strc = strcat(Sx, ST);
                Vid = vid1(:, :, :, x);
                cd frames\target
                app.EditField.Value=num2str((x/noOfTargetVideoFrames)*100);
                % exporting the frames
                pause(0.000001);%just to see the pecent value in display clearly
                imwrite(Vid, Strc);
                cd ..\..
            end
            results='Done';
        end
  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DataEditField
        function DataEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: EncryptVideoButton
        function EncryptVideoButtonPushed(app, event)

            processedResult=processVideo(app,app.DataEditField.Value);
            app.EditField.Value=processedResult;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EncryptVideoButton = uibutton(app.UIFigure, 'push');
            app.EncryptVideoButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptVideoButtonPushed, true);
            app.EncryptVideoButton.Text = 'Encrypt Video';
            app.DecryptVideoButton = uibutton(app.UIFigure, 'push');
            app.DecryptVideoButton.Text = 'Decrypt Video';
            app.DataEditFieldLabel = uilabel(app.UIFigure);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.DataEditField.ValueChangedFcn = createCallbackFcn(app, @DataEditFieldValueChanged, true);
            app.DataEditField.Placeholder = 'Enter the data';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end